classdef seminar3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FilesPanel                  matlab.ui.container.Panel
        SavefilteredsignalsButton   matlab.ui.control.Button
        LoadsignalsButton           matlab.ui.control.Button
        NofileselectedLabel         matlab.ui.control.Label
        LoadedfileLabel             matlab.ui.control.Label
        FiltersPanel                matlab.ui.container.Panel
        CutofffrequencySlider       matlab.ui.control.Slider
        CutofffrequencySliderLabel  matlab.ui.control.Label
        ApplayfiltersButton         matlab.ui.control.Button
        FilterDropDown              matlab.ui.control.DropDown
        FilterDropDownLabel         matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        FilteredSignalAxes          matlab.ui.control.UIAxes
        OriginalSignalAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        OriginalSignalData  % Store original EEG signal data
        FilteredSignalData  % Store filtered EEG signal data
        SamplingRate        % Sampling rate of EEG signals (adjust as needed)
        fs 
        tm
    end
    
    methods (Access = private)
        
        % Helper function to update axes with original and filtered signals
        function updateAxes(app)
            % Plot original signal
            t = (0:size(app.OriginalSignalData, 2) - 1) / app.SamplingRate;
            plot(app.OriginalSignalAxes, t, app.OriginalSignalData);
            title(app.OriginalSignalAxes, 'Original Signal');
            xlabel(app.OriginalSignalAxes, 'Time (s)');
            ylabel(app.OriginalSignalAxes, 'Amplitude');
            grid(app.OriginalSignalAxes, 'on');

            % Plot filtered signal (if available)
            if ~isempty(app.FilteredSignalData)
                plot(app.FilteredSignalAxes, t, app.FilteredSignalData);
                title(app.FilteredSignalAxes, 'Filtered Signal');
                xlabel(app.FilteredSignalAxes, 'Time (s)');
                ylabel(app.FilteredSignalAxes, 'Amplitude');
                grid(app.FilteredSignalAxes, 'on');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadsignalsButton
        function LoadsignalsButtonPushed(app, event)
             % Allow user to pick a file using a dialog
            [filename, pathname] = uigetfile({'*.edf', 'EDF Files (*.edf)'; '*.*', 'All Files (*.*)'}, 'Select EEG File');

            % Check if the user canceled the operation
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end

            cd(pathname)
        
            try  
                % Load EEG signals from the selected file
                [signals, fs, tm] = rdsamp(filename, 1:64);  % Adjust channel selection as needed
        
                % Store the original EEG signal data and update axes
                app.OriginalSignalData = signals';
                app.SamplingRate = fs;
                app.fs = fs;
                app.tm = tm;
                app.updateAxes();

    
                % Update the cutoff frequency sliders based on the signal characteristics
                maxFrequency = fs / 2;  % Nyquist frequency
                app.CutofffrequencySlider.Limits = [0, maxFrequency];
                app.CutofffrequencySlider.Limits = [0, maxFrequency];
                app.CutofffrequencySlider.Value = 0.1 * maxFrequency;  
                app.CutofffrequencySlider.Value = 0.9 * maxFrequency;  
    
    
                app.updateAxes();
    
                app.NofileselectedLabel.Text = filename;
                drawnow
            catch
                % Display an error message if the file couldn't be loaded
                errorMessage = 'Error loading EEG file. Please check the file path and format.';
                uialert(app.UIFigure, errorMessage, 'Error', 'Icon', 'error');
            end

           
        end

        % Button pushed function: ApplayfiltersButton
        function ApplayfiltersButtonPushed(app, event)
             % Check if EEG data is loaded
            if isempty(app.OriginalSignalData)
                errorMessage = 'Please load EEG signals before applying a filter.';
                uialert(app.UIFigure, errorMessage, 'Warning', 'Icon', 'warning');
                return;
            end
        
            % Get the selected filter type from the dropdown menu
            filterType = app.FilterDropDown.Value;
        
            % Get the cutoff frequency from the slider
            cutoffFrequency = app.CutofffrequencySlider.Value;
        
            % Design the filter based on the selected type and cutoff frequency
            switch filterType
                case 'highpass'
                    d = designfilt('highpassiir', 'FilterOrder', 4, 'HalfPowerFrequency', cutoffFrequency, 'SampleRate', app.SamplingRate);
                case 'lowpass'
                    d = designfilt('lowpassiir', 'FilterOrder', 4, 'HalfPowerFrequency', cutoffFrequency, 'SampleRate', app.SamplingRate);
                case 'bandpass'
                    % Adjust the bandwidth based on your requirements
                    bandwidth = 1;  % Change as needed
                    d = designfilt('bandpassiir', 'FilterOrder', 4, 'HalfPowerFrequency1', cutoffFrequency - bandwidth/2, 'HalfPowerFrequency2', cutoffFrequency + bandwidth/2, 'SampleRate', app.SamplingRate);
                case 'bandstop'
                    % Adjust the bandwidth based on your requirements
                    bandwidth = 1;  % Change as needed
                    d = designfilt('bandstopiir', 'FilterOrder', 4, 'HalfPowerFrequency1', cutoffFrequency - bandwidth/2, 'HalfPowerFrequency2', cutoffFrequency + bandwidth/2, 'SampleRate', app.SamplingRate);
                otherwise
                    return;
            end
        
            % Apply the filter to the original signal data
            app.FilteredSignalData = filtfilt(d, app.OriginalSignalData);
        
            % Update the axes with the filtered signal
            app.updateAxes();
        end

        % Button pushed function: SavefilteredsignalsButton
        function SavefilteredsignalsButtonPushed(app, event)
            % Transpose the data to match EDF format (if necessary)
            filteredData = app.FilteredSignalData;
            if size(filteredData, 1) > size(filteredData, 2)
                filteredData = filteredData';
            end

             % Check if filtered signals are available
            if isempty(app.FilteredSignalData)
                errorMessage = 'No filtered signals to save. Please apply filters first.';
                uialert(app.UIFigure, errorMessage, 'Warning', 'Icon', 'warning');
                return;
            end
        
            % Allow the user to pick a location for saving the filtered signals
            [filename, pathname] = uiputfile({'*.mat', 'MAT Files (*.mat)'; '*.*', 'All Files (*.*)'}, 'Save Filtered Signals As MAT');
        
            % Check if the user canceled the operation
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
        
            % Construct the full file path
            saveFilePath = fullfile(pathname, filename);
       
            
            % Save the filtered signals to a MATLAB file
            save(saveFilePath, 'filteredData', 'app', '-mat');


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FiltersPanel = uipanel(app.LeftPanel);
            app.FiltersPanel.Title = 'Filters';
            app.FilterDropDownLabel = uilabel(app.FiltersPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.FiltersPanel);
            app.FilterDropDown.Items = {'highpass', 'lowpass', 'bandpass', 'bandstop'};
            app.FilterDropDown.Value = 'highpass';
            app.ApplayfiltersButton = uibutton(app.FiltersPanel, 'push');
            app.ApplayfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplayfiltersButtonPushed, true);
            app.ApplayfiltersButton.Text = 'Applay filters';
            app.CutofffrequencySliderLabel = uilabel(app.FiltersPanel);
            app.CutofffrequencySliderLabel.Text = 'Cutoff frequency';
            app.CutofffrequencySlider = uislider(app.FiltersPanel);
            app.FilesPanel = uipanel(app.LeftPanel);
            app.FilesPanel.Title = 'Files';
            app.LoadedfileLabel = uilabel(app.FilesPanel);
            app.LoadedfileLabel.Text = 'Loaded file: ';
            app.NofileselectedLabel = uilabel(app.FilesPanel);
            app.NofileselectedLabel.Text = 'No file selected';
            app.LoadsignalsButton = uibutton(app.FilesPanel, 'push');
            app.LoadsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsignalsButtonPushed, true);
            app.LoadsignalsButton.Text = 'Load signals';
            app.SavefilteredsignalsButton = uibutton(app.FilesPanel, 'push');
            app.SavefilteredsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @SavefilteredsignalsButtonPushed, true);
            app.SavefilteredsignalsButton.Text = 'Save filtered signals';
            app.RightPanel = uipanel(app.GridLayout);
            app.OriginalSignalAxes = uiaxes(app.RightPanel);
            title(app.OriginalSignalAxes, 'Original signals')
            xlabel(app.OriginalSignalAxes, 'Time')
            ylabel(app.OriginalSignalAxes, 'Amplitude')
            zlabel(app.OriginalSignalAxes, 'Z')
            app.FilteredSignalAxes = uiaxes(app.RightPanel);
            title(app.FilteredSignalAxes, 'Filter signals')
            xlabel(app.FilteredSignalAxes, 'Time')
            ylabel(app.FilteredSignalAxes, 'Amplitude')
            zlabel(app.FilteredSignalAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seminar3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end